classdef KIRBY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CSTTab                          matlab.ui.container.Tab
        FrequencyGHzLabel               matlab.ui.control.Label
        CSTFreq                         matlab.ui.control.NumericEditField
        CSTMode                         matlab.ui.container.ButtonGroup
        CSTAbsGain                      matlab.ui.control.RadioButton
        CSTAbsTheta                     matlab.ui.control.RadioButton
        CSTPhaseTheta                   matlab.ui.control.RadioButton
        CSTAbsPhi                       matlab.ui.control.RadioButton
        CSTAxial                        matlab.ui.control.RadioButton
        CSTPhasePhi                     matlab.ui.control.RadioButton
        Export2Excel                    matlab.ui.control.Button
        FekoTab                         matlab.ui.container.Tab
        FekoExport2Excel                matlab.ui.control.Button
        FekoFreq                        matlab.ui.control.NumericEditField
        FrequencyGHzLabel_2             matlab.ui.control.Label
        RFXTab                          matlab.ui.container.Tab
        RFXMode                         matlab.ui.container.ButtonGroup
        RFXAbsGain                      matlab.ui.control.RadioButton
        RFXAbsTheta                     matlab.ui.control.RadioButton
        RFXPhaseTheta                   matlab.ui.control.RadioButton
        RFXAbsPhi                       matlab.ui.control.RadioButton
        RFXRHCP                         matlab.ui.control.RadioButton
        RFXPhasePhi                     matlab.ui.control.RadioButton
        RFXLHCP                         matlab.ui.control.RadioButton
        RFXAxial                        matlab.ui.control.RadioButton
        RFXFreq                         matlab.ui.control.NumericEditField
        FrequencyGHzLabel_3             matlab.ui.control.Label
        SwitchAzimuthandElevationLabel  matlab.ui.control.Label
        RFXSwitchAzEl                   matlab.ui.control.Switch
        RFXExport2Excel                 matlab.ui.control.Button
        ECTab                           matlab.ui.container.Tab
        EnterFrequenciesofInterestLabel  matlab.ui.control.Label
        ECFreqList                      matlab.ui.control.EditField
        comment5                        matlab.ui.control.Label
        AzimuthResolutiondegLabel       matlab.ui.control.Label
        ECAzRes                         matlab.ui.control.NumericEditField
        ElevationResolutiondegLabel     matlab.ui.control.Label
        ECElRes                         matlab.ui.control.NumericEditField
        CurrentFileElevationLabel       matlab.ui.control.Label
        ECCurrEl                        matlab.ui.control.NumericEditField
        ECExport2Excel                  matlab.ui.control.Button
        ECmeasMode                      matlab.ui.container.ButtonGroup
        ECmeasAbsGain                   matlab.ui.control.RadioButton
        ECmeasPhase                     matlab.ui.control.RadioButton
        PlotFiguresPanel                matlab.ui.container.Panel
        SourceButtonGroup               matlab.ui.container.ButtonGroup
        InputFileButton                 matlab.ui.control.RadioButton
        OutputFileButton                matlab.ui.control.RadioButton
        FrequencyGHzLabel_4             matlab.ui.control.Label
        PlotFreq                        matlab.ui.control.NumericEditField
        ResultListPanel                 matlab.ui.container.Panel
        sphere                          matlab.ui.control.CheckBox
        cartesian                       matlab.ui.control.CheckBox
        contour                         matlab.ui.control.CheckBox
        BW                              matlab.ui.control.CheckBox
        el_const                        matlab.ui.control.CheckBox
        az_const                        matlab.ui.control.CheckBox
        BWInput                         matlab.ui.control.EditField
        dBLabel                         matlab.ui.control.Label
        el_constInput                   matlab.ui.control.EditField
        az_constInput                   matlab.ui.control.EditField
        Plot                            matlab.ui.control.Button
        InputOutputAddressPanel         matlab.ui.container.Panel
        BrowseInput                     matlab.ui.control.Button
        BrowseOutput                    matlab.ui.control.Button
        comment1                        matlab.ui.control.Label
        EnterInputFileAddressLabel_5    matlab.ui.control.Label
        InputAdd                        matlab.ui.control.EditField
        EnterOutputFilePathEditFieldLabel_5  matlab.ui.control.Label
        OutputPath                      matlab.ui.control.EditField
        EnterOutputFileNameLabel_5      matlab.ui.control.Label
        OutputName                      matlab.ui.control.EditField
    end


    properties (Access = public)
        %enter global variables here
    end


    methods (Access = private)

        % Button pushed function: FekoExport2Excel
        function FekoExport2ExcelButtonPushed(app, event)
            Freq = app.FekoFreq.Value;
            InputFileAdd = app.InputAdd.Value;
            OutputFilePath = app.OutputPath.Value;
            OutputFileName = app.OutputName.Value;
            if (strcmp(OutputFileName, 'Output Name Not Required'))
                OutputFileAdd = OutputFilePath;
            else
                OutputFileAdd = strcat(OutputFilePath,'\',OutputFileName,'.xlsx');
            end
            
            %start Feko2Excel function
            Feko2excel(Freq, InputFileAdd, OutputFileAdd);
        end

        % Button pushed function: Export2Excel
        function Export2ExcelButtonPushed(app, event)
            %pick mode according CSTMode buttons
            if (app.CSTAbsGain.Value == 1)
                Mode = 1;
            elseif (app.CSTAbsTheta.Value == 1)
                Mode = 2;
            elseif (app.CSTPhaseTheta.Value == 1)
                Mode = 3;
            elseif (app.CSTAbsPhi.Value == 1)
                Mode = 4;
            elseif (app.CSTPhasePhi.Value == 1)
                Mode = 5;
            elseif (app.CSTAxial.Value == 1)
                Mode = 6;
            end

            Freq = app.CSTFreq.Value;
            InputFileAdd = app.InputAdd.Value;
            OutputFilePath = app.OutputPath.Value;
            OutputFileName = app.OutputName.Value;
            if (strcmp(OutputFileName, 'Output Name Not Required'))
                OutputFileAdd = OutputFilePath;
            else
                OutputFileAdd = strcat(OutputFilePath,'\',OutputFileName,'.xlsx');
            end
            
            %start CST2Excel function
            CST2excel(Freq, Mode, InputFileAdd, OutputFileAdd);
        end

        % Button pushed function: RFXExport2Excel
        function RFXExport2ExcelButtonPushed(app, event)
            %pick mode according CSTMode buttons
            if (app.RFXAbsGain.Value == 1)
                Mode = 1;
            elseif (app.RFXAbsTheta.Value == 1)
                Mode = 2;
            elseif (app.RFXAbsPhi.Value == 1)
                Mode = 3;
            elseif (app.RFXPhaseTheta.Value == 1)
                Mode = 4;
            elseif (app.RFXPhasePhi.Value == 1)
                Mode = 5;
            elseif (app.RFXRHCP.Value == 1)
                Mode = 6;
            elseif (app.RFXLHCP.Value == 1)
                Mode = 7;
            elseif (app.RFXAxial.Value == 1)
                Mode = 8;
            end

            SwitchAzEl = app.RFXSwitchAzEl.Value;
            if(strcmp(SwitchAzEl,'Yes'))
                clear SwitchAzEl;
                SwitchAzEl = 1;
            else
                clear SwitchAzEl;
                SwitchAzEl = 0;    
            end
            Freq = app.RFXFreq.Value;
            InputFileAdd = app.InputAdd.Value;
            OutputFilePath = app.OutputPath.Value;
            OutputFileName = app.OutputName.Value;
            if (strcmp(OutputFileName, 'Output Name Not Required'))
                OutputFileAdd = OutputFilePath;
            else
                OutputFileAdd = strcat(OutputFilePath,'\',OutputFileName,'.xlsx');
            end
            
            %start RFXmeas2Excel function
            RFXmeas2excel(Freq, Mode,SwitchAzEl, InputFileAdd, OutputFileAdd);
        end

        % Button pushed function: ECExport2Excel
        function ECExport2ExcelButtonPushed(app, event)
            %pick mode according ECmeasMode buttons
            if (app.ECmeasAbsGain.Value == 1)
                Mode = 1;
            elseif (app.ECmeasPhase.Value == 1)
                Mode = 2;
            end
            
            Freq = app.ECFreqList.Value;
            az_res = app.ECAzRes.Value;
            el_res = app.ECElRes.Value;
            CurrEl = app.ECCurrEl.Value;
            InputFileAdd = app.InputAdd.Value;
            OutputFilePath = app.OutputPath.Value;
            OutputFileName = app.OutputName.Value;
            if (strcmp(OutputFileName, 'Output Name Not Required'))
                OutputFileAdd = OutputFilePath;
            else
                OutputFileAdd = strcat(OutputFilePath,'\',OutputFileName,'.xlsx');
            end


            %start ECmeas2Excel function
            ECmeas2excel(Freq, Mode, az_res, el_res, CurrEl, InputFileAdd, OutputFileAdd);
        end

        % Button pushed function: BrowseInput
        function BrowseInputButtonPushed(app, event)
            [name, path] = uigetfile('*.*');
            if (((num2str(name(1))) == '0')||((num2str(path(1))) == '0'))
                fprintf('Please specify file name and path\n');
            else
                app.InputAdd.Value = strcat(path, name);
            end 
        end

        % Button pushed function: BrowseOutput
        function BrowseOutputButtonPushed(app, event)
            str = {'select file','select folder'};
            s = listdlg('PromptString','Select a file:','SelectionMode','single','ListString',str);
            if (s==1)
                %choose file
                [name,path] = uigetfile('*.*');
                if (((num2str(name(1))) == '0')||((num2str(path(1))) == '0'))
                    fprintf('Please specify  output file name and path\n');
                else
                    app.OutputPath.Value = strcat(path, name);
                    app.OutputName.Value = strcat('Output Name Not Required');
                end 
            else
                %choose folder
                path = uigetdir('*.*');
                if ((num2str(path(1))) == '0')
                    fprintf('Please specify  output file path\n');
                else
                    app.OutputPath.Value = path;
                end
            end
        end

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            skip = 0;
            %Source - input file button
            if (app.InputFileButton.Value) 
                if isempty(app.InputAdd.Value) %if there is no input address
                    fprintf('Please specify  input file address\n');
                    skip = 1;
                else %if input address exists
                    InputFileAdd = app.InputAdd.Value;
                end
            %Source - output file button
            else 
                if (isempty(app.OutputPath.Value) || isempty(app.OutputName.Value)) %if there is no output name or path
                    fprintf('Please specify output file name & path\n');
                    skip = 1;
                else %if input address exists
                    OutputFilePath = app.OutputPath.Value;
                    OutputFileName = app.OutputName.Value;
                    if (strcmp(OutputFileName, 'Output Name Not Required'))
                        InputFileAdd = OutputFilePath;
                    else
                        InputFileAdd = strcat(OutputFilePath,'\',OutputFileName,'.xlsx');
                    end
                end
            end
            %extracting the frequency
            if isempty(app.PlotFreq.Value)
                fprintf('Please specify requested frequency\n');
                skip = 1;
            else
                Freq = app.PlotFreq.Value;
            end
            
            %if all inputs are not empty
            if(skip == 0)
                %use our function to extract the data from the correct excel sheet
                Mat = ExtractMatrix(Freq, InputFileAdd);
                [~,InputMode] = (xlsread(InputFileAdd,1,'C2:C2'));
                InputMode = char(InputMode);
                %devide Mat into azimuth, elevation and data
                az = Mat(1,2:length(Mat(1,:)));
                el = Mat(2:length(Mat(:,1)),1);
                data = Mat(2:length(Mat(:,1)), 2:length(Mat(1,:)));
                
                %ploting according to Request List check box 
                if (app.sphere.Value || app.el_const.Value || app.az_const.Value) %spherical plots
                    SphericalPlotGroup(Freq, el, az, data, app.sphere.Value, app.el_const.Value, app.az_const.Value, app.el_constInput.Value, app.az_constInput.Value);
                end
                
                if (app.cartesian.Value || app.contour.Value || app.BW.Value) %cartesian plots
                    CartesianPlotGroup(Freq, el, az, data, app.cartesian.Value, app.contour.Value, app.BW.Value, app.BWInput.Value, InputMode);
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CSTTab = uitab(app.TabGroup);
            app.CSTTab.Title = 'CST';
            app.FrequencyGHzLabel = uilabel(app.CSTTab);
            app.FrequencyGHzLabel.Text = 'Frequency [GHz]:';
            app.CSTFreq = uieditfield(app.CSTTab, 'numeric');
            app.CSTMode = uibuttongroup(app.CSTTab);
            app.CSTMode.Title = 'Extract from data file:';
            app.CSTAbsGain = uiradiobutton(app.CSTMode);
            app.CSTAbsGain.Text = 'Absolute Gain [dBi]';
            app.CSTAbsGain.Value = true;
            app.CSTAbsTheta = uiradiobutton(app.CSTMode);
            app.CSTAbsTheta.Text = 'Absolute Theta [dBi]';
            app.CSTPhaseTheta = uiradiobutton(app.CSTMode);
            app.CSTPhaseTheta.Text = 'Theta Phase [deg.]';
            app.CSTAbsPhi = uiradiobutton(app.CSTMode);
            app.CSTAbsPhi.Text = 'Absolute Phi [dBi]';
            app.CSTAxial = uiradiobutton(app.CSTMode);
            app.CSTAxial.Text = 'Axial Ratio [dB]';
            app.CSTPhasePhi = uiradiobutton(app.CSTMode);
            app.CSTPhasePhi.Text = 'Phase Phi [deg.]';
            app.Export2Excel = uibutton(app.CSTTab, 'push');
            app.Export2Excel.ButtonPushedFcn = createCallbackFcn(app, @Export2ExcelButtonPushed, true);
            app.Export2Excel.Text = 'Export to Excel';
            app.FekoTab = uitab(app.TabGroup);
            app.FekoTab.Title = 'Feko';
            app.FekoExport2Excel = uibutton(app.FekoTab, 'push');
            app.FekoExport2Excel.ButtonPushedFcn = createCallbackFcn(app, @FekoExport2ExcelButtonPushed, true);
            app.FekoExport2Excel.Text = 'Export to Excel';
            app.FekoFreq = uieditfield(app.FekoTab, 'numeric');
            app.FrequencyGHzLabel_2 = uilabel(app.FekoTab);
            app.FrequencyGHzLabel_2.Text = 'Frequency [GHz]:';
            app.RFXTab = uitab(app.TabGroup);
            app.RFXTab.Title = 'RFX';
            app.RFXMode = uibuttongroup(app.RFXTab);
            app.RFXMode.Title = 'Extract from data file:';
            app.RFXAbsGain = uiradiobutton(app.RFXMode);
            app.RFXAbsGain.Text = 'Absolute Gain [dBi]';
            app.RFXAbsGain.Value = true;
            app.RFXAbsTheta = uiradiobutton(app.RFXMode);
            app.RFXAbsTheta.Text = 'Absolute Theta [dBi]';
            app.RFXPhaseTheta = uiradiobutton(app.RFXMode);
            app.RFXPhaseTheta.Text = 'Theta Phase [deg.]';
            app.RFXAbsPhi = uiradiobutton(app.RFXMode);
            app.RFXAbsPhi.Text = 'Absolute Phi [dBi]';
            app.RFXRHCP = uiradiobutton(app.RFXMode);
            app.RFXRHCP.Text = 'RHCP [dB]';
            app.RFXPhasePhi = uiradiobutton(app.RFXMode);
            app.RFXPhasePhi.Text = 'Phase Phi [deg.]';
            app.RFXLHCP = uiradiobutton(app.RFXMode);
            app.RFXLHCP.Text = 'LHCP [dB]';
            app.RFXAxial = uiradiobutton(app.RFXMode);
            app.RFXAxial.Text = 'Axial Ratio [dB]';
            app.RFXFreq = uieditfield(app.RFXTab, 'numeric');
            app.FrequencyGHzLabel_3 = uilabel(app.RFXTab);
            app.FrequencyGHzLabel_3.Text = 'Frequency [GHz]:';
            app.SwitchAzimuthandElevationLabel = uilabel(app.RFXTab);
            app.SwitchAzimuthandElevationLabel.Text = {'Switch Azimuth'; 'and Elevation'};
            app.RFXSwitchAzEl = uiswitch(app.RFXTab, 'slider');
            app.RFXSwitchAzEl.Items = {'No', 'Yes'};
            app.RFXSwitchAzEl.Value = 'No';
            app.RFXExport2Excel = uibutton(app.RFXTab, 'push');
            app.RFXExport2Excel.ButtonPushedFcn = createCallbackFcn(app, @RFXExport2ExcelButtonPushed, true);
            app.RFXExport2Excel.Text = 'Export to Excel';
            app.ECTab = uitab(app.TabGroup);
            app.ECTab.Title = 'Echoless Chamber';
            app.EnterFrequenciesofInterestLabel = uilabel(app.ECTab);
            app.EnterFrequenciesofInterestLabel.Text = 'Enter Frequencies of Interest:';
            app.ECFreqList = uieditfield(app.ECTab, 'text');
            app.comment5 = uilabel(app.ECTab);
            app.comment5.Text = '*Frequencies must be devided by semicolons.';
            app.AzimuthResolutiondegLabel = uilabel(app.ECTab);
            app.AzimuthResolutiondegLabel.Text = 'Azimuth Resolution [deg.]:';
            app.ECAzRes = uieditfield(app.ECTab, 'numeric');
            app.ECAzRes.Value = 1;
            app.ElevationResolutiondegLabel = uilabel(app.ECTab);
            app.ElevationResolutiondegLabel.Text = 'Elevation Resolution [deg.]:';
            app.ECElRes = uieditfield(app.ECTab, 'numeric');
            app.ECElRes.Value = 1;
            app.CurrentFileElevationLabel = uilabel(app.ECTab);
            app.CurrentFileElevationLabel.Text = 'Current File Elevation:';
            app.ECCurrEl = uieditfield(app.ECTab, 'numeric');
            app.ECExport2Excel = uibutton(app.ECTab, 'push');
            app.ECExport2Excel.ButtonPushedFcn = createCallbackFcn(app, @ECExport2ExcelButtonPushed, true);
            app.ECExport2Excel.Text = 'Export to Excel';
            app.ECmeasMode = uibuttongroup(app.ECTab);
            app.ECmeasMode.Title = 'Extract from data file:';
            app.ECmeasAbsGain = uiradiobutton(app.ECmeasMode);
            app.ECmeasAbsGain.Text = 'Absolute Gain [dBi]';
            app.ECmeasAbsGain.Value = true;
            app.ECmeasPhase = uiradiobutton(app.ECmeasMode);
            app.ECmeasPhase.Text = 'Phase [deg.]';
            app.PlotFiguresPanel = uipanel(app.UIFigure);
            app.PlotFiguresPanel.Title = 'Plot Figures';
            app.SourceButtonGroup = uibuttongroup(app.PlotFiguresPanel);
            app.SourceButtonGroup.Title = 'Source:';
            app.InputFileButton = uiradiobutton(app.SourceButtonGroup);
            app.InputFileButton.Text = 'Input File';
            app.InputFileButton.Value = true;
            app.OutputFileButton = uiradiobutton(app.SourceButtonGroup);
            app.OutputFileButton.Text = 'Output File';
            app.FrequencyGHzLabel_4 = uilabel(app.PlotFiguresPanel);
            app.FrequencyGHzLabel_4.Text = 'Frequency [GHz]:';
            app.PlotFreq = uieditfield(app.PlotFiguresPanel, 'numeric');
            app.ResultListPanel = uipanel(app.PlotFiguresPanel);
            app.ResultListPanel.Title = 'Result List:';
            app.sphere = uicheckbox(app.ResultListPanel);
            app.sphere.Text = '3D Spherical';
            app.cartesian = uicheckbox(app.ResultListPanel);
            app.cartesian.Text = '3D Cartesian';
            app.contour = uicheckbox(app.ResultListPanel);
            app.contour.Text = 'Contour Map';
            app.BW = uicheckbox(app.ResultListPanel);
            app.BW.Text = 'Band Width:';
            app.el_const = uicheckbox(app.ResultListPanel);
            app.el_const.Text = 'Polar Plot - Const. Elevation:';
            app.az_const = uicheckbox(app.ResultListPanel);
            app.az_const.Text = 'Polar Plot - Const. Azimuth:';
            app.BWInput = uieditfield(app.ResultListPanel, 'text');
            app.BWInput.Value = '3';
            app.dBLabel = uilabel(app.ResultListPanel);
            app.dBLabel.Text = 'dB';
            app.el_constInput = uieditfield(app.ResultListPanel, 'text');
            app.el_constInput.Value = '0';
            app.az_constInput = uieditfield(app.ResultListPanel, 'text');
            app.az_constInput.Value = '0';
            app.Plot = uibutton(app.PlotFiguresPanel, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.InputOutputAddressPanel = uipanel(app.UIFigure);
            app.InputOutputAddressPanel.Title = 'Input & Output Address';
            app.BrowseInput = uibutton(app.InputOutputAddressPanel, 'push');
            app.BrowseInput.ButtonPushedFcn = createCallbackFcn(app, @BrowseInputButtonPushed, true);
            app.BrowseInput.Text = 'Browse...';
            app.BrowseOutput = uibutton(app.InputOutputAddressPanel, 'push');
            app.BrowseOutput.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutput.Text = 'Browse...';
            app.comment1 = uilabel(app.InputOutputAddressPanel);
            app.comment1.Text = '*If the file does not exist, a new file will be created in it''s name';
            app.EnterInputFileAddressLabel_5 = uilabel(app.InputOutputAddressPanel);
            app.EnterInputFileAddressLabel_5.Text = 'Enter Input File Address:';
            app.InputAdd = uieditfield(app.InputOutputAddressPanel, 'text');
            app.EnterOutputFilePathEditFieldLabel_5 = uilabel(app.InputOutputAddressPanel);
            app.EnterOutputFilePathEditFieldLabel_5.Text = 'Enter Output File Path:';
            app.OutputPath = uieditfield(app.InputOutputAddressPanel, 'text');
            app.EnterOutputFileNameLabel_5 = uilabel(app.InputOutputAddressPanel);
            app.EnterOutputFileNameLabel_5.Text = 'Enter Output File Name:';
            app.OutputName = uieditfield(app.InputOutputAddressPanel, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = KIRBY

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end